classdef App_Mario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CANStatusLampLabel       matlab.ui.control.Label
        CANStatusLamp            matlab.ui.control.Lamp
        CANconfigurationButton   matlab.ui.control.Button
        StartStopSamplingButton  matlab.ui.control.Button
        CANStatusLamp_2Label     matlab.ui.control.Label
        CANStatusLamp_2          matlab.ui.control.Lamp
        SamplingH3Label          matlab.ui.control.Label
        TestingsoftwareLabel     matlab.ui.control.Label
        MoveankleButton          matlab.ui.control.Button
        InicializeButton         matlab.ui.control.Button
        InicilizestatusLabel     matlab.ui.control.Label
        InicializeStatusLamp     matlab.ui.control.Lamp
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_6                 matlab.ui.control.UIAxes
        UIplotrhip               matlab.ui.control.UIAxes
        UIplotrknee              matlab.ui.control.UIAxes
        UIplotrankle             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        
        canch=canChannel('NI','CAN1');
        
% %  ---------------------- Elkin Variables ------------------------- 
%         timePass=1;
%         interv = 1000 ; % considering 1000 samples
%         x = (1:1000)-(1:1000);
%         x2 = (1:1000)-(1:1000);
% 
%         xRH = (1:1000)-(1:1000);
%         xRK = (1:1000)-(1:1000);
%         xRA = (1:1000)-(1:1000);
%         
%         xLH = (1:1000)-(1:1000);
%         xLK = (1:1000)-(1:1000);
%         xLA = (1:1000)-(1:1000);
%          
%         flagCANConect=0;
%         value1=0;
%         value2=0;
%         
%         valueRH=0;
%         valueRK=0;
%         valueRA=0;
%         
%         valueLH=0;
%         valueLK=0;
%         valueLA=0;
%         tableRx=0;
        
% % ---------------- GLOBAL INITIALIZING  ----------------------------- 
                                    %ORIGINALS
        rhip_global_init  = 80;     %80
        lhip_global_init  = 80;     %80
        rknee_global_init = 90;     %90
        lknee_global_init = 90;     %90
        rankle_global_init = 0;     %0
        lankle_global_init = 0;     %
        
        %% CAN conexion and settings
        PossitionControl = 1;
        MotorDisabled    = 4;
        MotorStoped      = 5;
        
        speed = 10/1000;
        
        %% Trajectory Try ankle
        tetaankle_init =  0;
        tetaankle_max  = 25;
        
        T = 1;
        timeDiv = 50; %Numero De puntos
        
        hy1 = 0;
        ky1 = 0;
        ay1 = 0;
    
    end

    methods (Access = private)
       function [hexstring]=ndec2hex(~,x,n)
            % x : input decimal number
            % n :   number of bits to perform 2's complements
            % hexstring : hex representation of two's complement of x 
            x = x + (x<0).*2^n; 
            hexstring = dec2hex(x, ceil(n/4));
       end
       
       function [x,xp,xpp,t]=cicloidale(~,S0,Sf,T,dT)
            dS = Sf-S0;
            
            %dT=T/timeDiv; % time step    
            t = [0:1/dT:T];
            
            for i=1:length(t)
            x(i)   =  S0 + dS*(t(i)/T - (1/(2*pi))*sin((2*pi*t(i))/T));
            xp(i)  = (dS/T)*(1-cos((2*pi*t(i))/T));
            xpp(i) = (dS/T^2)*2*pi*sin((2*pi*t(i))/T);
            end
       end
        
            
       function [] = recibe_bucle(app)                                      % FUNCION GRAFICAR
        rxMsg = receive(app.canch, Inf, 'OutputFormat', 'timetable');
        dato  = cell2mat(rxMsg.Data);
        
        for i=1:size(dato,1)
            message = canMessage(500,false,8);
            message.Data=dato(i,:);
    
            Right_Hip_Unpack(i)   = unpack(message,  0, 8, 'BigEndian', 'int32');
            Right_Knee_Unpack(i)  = unpack(message,  8, 8, 'BigEndian', 'int32');
            Right_Ankle_Unpack(i) = unpack(message, 16, 8, 'BigEndian', 'int32'); 
%             Left_Hip_Unpack(i)   = unpack(message, 24, 8, 'BigEndian', 'int32');
%             Left_Knee_Unpack(i)  = unpack(message, 32, 8, 'BigEndian', 'int32');
%             Left_Ankle_Unpack(i) = unpack(message, 40, 8, 'BigEndian', 'int32');
        end
        
        hy0 = app.hy1
        ky0 = app.ky1;
        ay0 = app.ay1;
            
        sh = size(Right_Hip_Unpack)
        sk = size(Right_Knee_Unpack);
        sa = size(Right_Ankle_Unpack);
        
        app.hy1 = hy0 + sh(2)
        app.ky1 = ky0 + sk(2);
        app.ay1 = ay0 + sa(2);
        
        hy0:app.hy1-1
        
        plot(app.UIplotrhip,   hy0:app.hy1-1,  Right_Hip_Unpack,   '-b');
        plot(app.UIplotrknee,  ky0:app.ky1-1,  Right_Knee_Unpack,  '-b');
        plot(app.UIplotrankle, ay0:app.ay1-1,  Right_Ankle_Unpack, '-b');
    
        app.CANStatusLamp_2.Color = 'green';
       end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CANconfigurationButton
        function CANconfigurationButtonPushed(app, event)
%            addpath(genpath('C:\Users\Usuario\Documents\Cajal\H3'));      % CONFIG INICIAL
                    
%            IDPositionAngular=[110];
            filterAllowOnly(app.canch, 110, 'Standard');
    
            configBusSpeed(app.canch,1000000);
            start(app.canch)  
           
            app.CANStatusLamp.Color = 'green';
        end

        % Button pushed function: StartStopSamplingButton
        function StartStopSamplingButtonPushed(app, event)
                                                                            % RECIBIR DATOS
                  
%                   app.tableRx=load('table2');
                               
                  app.canch.MessageReceivedFcn = @receivingFcnApp;
                  app.canch.MessageReceivedFcnCount = 30;
                                      
                  app.CANStatusLamp_2.Color = 'green';
            
       function receivingFcnApp(canch)                                      % CALLBACK GRAFICAR
% % OPCION 1 (MARIO):
%             rxMsg = receive(canch, Inf, 'OutputFormat', 'timetable');
%             dato=cell2mat(rxMsg.Data);
%             
%             for i=1:size(dato,1)
%                 message = canMessage(500,false,8);
%                 message.Data=dato(i,:);
%         
%                 Right_Hip_Unpack(i) = unpack(message,0,8,'BigEndian','int32');
%                 Right_Knee_Unpack(i) = unpack(message,8,8,'BigEndian','int32');
%                 Right_Ankle_Unpack(i) = unpack(message,16,8,'BigEndian','int32'); 
%                 Left_Hip_Unpack(i) = unpack(message,24,8,'BigEndian','int32');
%                 Left_Knee_Unpack(i) = unpack(message,32,8,'BigEndian','int32');
%                 Left_Ankle_Unpack(i) = unpack(message,40,8,'BigEndian','int32');
% 
%             end
%             plot(app.UIplotrhip,Right_Hip_Unpack,'-b');
%             plot(app.UIplotrknee,Right_Knee_Unpack,'-b');
%             plot(app.UIplotrankle,Right_Ankle_Unpack,'-b');
%             
%             app.CANStatusLamp_2.Color = 'green';
            
% ------------------------------------------------------------------------
% OPCION 2 (ELKIN):
            rxMsg = receive(canch, Inf, 'OutputFormat', 'timetable');
            
            rxMsgTable=timetable2table(rxMsg);
            app.tableRx.table2=[app.tableRx.table2; rxMsgTable];
            writetable(rxMsgTable, ['test' char(rxMsg.Time(1)) '.txt'])
                                
            ID=find(rxMsg.ID==110);  
            dato=cell2mat(rxMsg.Data(ID(1)));
            
            message = canMessage(500,false,8);
            message.Data=dato;
            
            app.valueRH = unpack(message,0,8,'BigEndian','int32');
            app.valueRK = unpack(message,8,8,'BigEndian','int32');
            app.valueRA = unpack(message,16,8,'BigEndian','int32');
            app.valueLH = unpack(message,24,8,'BigEndian','int32');
            app.valueLK = unpack(message,32,8,'BigEndian','int32');
            app.valueLA = unpack(message,40,8,'BigEndian','int32');
             
            app.xRH(app.timePass) = app.valueRH;
            app.xRK(app.timePass) = app.valueRK;
            app.xRA(app.timePass) = app.valueRA;
            
            app.xLH(app.timePass) = app.valueLH;
            app.xLK(app.timePass) = app.valueLK;
            app.xLA(app.timePass) = app.valueLA;
            
            plot(app.UIAxes, 1:app.timePass, app.xRH(1:app.timePass),'b');
            drawnow;
            plot(app.UIAxes_2, 1:app.timePass, app.xLH(1:app.timePass),'b');
            drawnow;
            plot(app.UIAxes_3, 1:app.timePass, app.xRK(1:app.timePass),'b');
            drawnow;
            plot(app.UIAxes_4, 1:app.timePass, app.xLK(1:app.timePass),'b');
            drawnow;
            plot(app.UIAxes_5, 1:app.timePass, app.xRA(1:app.timePass),'b');
            drawnow;
            plot(app.UIAxes_6, 1:app.timePass, app.xLA(1:app.timePass),'b');
            drawnow;
            app.timePass=app.timePass+1;

        end
            
        end

        % Button pushed function: MoveankleButton
        function MoveankleButtonPushed(app, event)
            % Try ankle
            messagePosSetPoint=canMessage(72,false,6);
            [yankle_try1,~,~,~]=cicloidale(app,app.tetaankle_init,app.tetaankle_max,app.T,app.timeDiv);
            [yankle_try2,~,~,~]=cicloidale(app,app.tetaankle_max,app.tetaankle_init,app.T,app.timeDiv);
            yankle_try = [yankle_try1 yankle_try2]';
            
            %  Send ankle_try        
            for i=1:length(yankle_try)% send position function;   
                pause(app.speed)%no enviar mensajes en menos de 2ms, pero ponle 5ms
                messagePosSetPoint.Data=hex2dec(ndec2hex(app,[80 90 roundn(yankle_try(i),0) 80 90 0],8))';
            %    messagePosSetPoint.Data(3)=hex2dec(ndec2hex(app,[roundn(yankle_try(i),0)],8))'; 
                transmit(app.canch,messagePosSetPoint)  
                recibe_bucle(app);
%                rxMsg(i) = receive(app.canch, 1,'OutputFormat','timetable'); % para el bucle
%                app.CANStatusLamp_2.Color = 'red';
            end
        end

        % Button pushed function: InicializeButton
        function InicializeButtonPushed(app, event)
                       
            %Min angles                                    
            messageMinAngles=canMessage(75,false,6);
            messageMinAngles.Data=(hex2dec(ndec2hex(app,[-25 0 -25 -25 0 -25],8)))';       %NOT TO TOUCH
            
            %Max angles                                    
            messageMaxAngles=canMessage(80,false,6);
            messageMaxAngles.Data=(hex2dec(ndec2hex(app,[100 100 25 100 100 25],8)))';     %NOT TO TOUCH
            
            %Percentage of assistance (40-90)
            messagePercentageAssistance=canMessage(76,false,6);
            messagePercentageAssistance.Data=(hex2dec(ndec2hex(app,[20 20 20 20 20 20],8)))';
            
            %Position set point (punto iniziale)
            messagePosSetPoint=canMessage(72,false,6);
            messagePosSetPoint.Data=(hex2dec(ndec2hex(app,[app.rhip_global_init app.rknee_global_init app.rankle_global_init...
                                                       app.lhip_global_init app.lknee_global_init app.lankle_global_init],8)))';
            %messagePosSetPoint.Data=(hex2dec(ndec2hex([80 90 0  80 90 0],8)))';
            %messagePosSetPoint.Data=(hex2dec(ndec2hex([0 0 0  0 0 0],8)))';
            
            %Type of control
            messageTypeControl=canMessage(71,false,6);
 
            % LEGEND: [R_Hip R_Knee R_Ankle L_Hip L_Knee L_Ankle]
            messageTypeControl.Data=(hex2dec(ndec2hex(app,[app.PossitionControl app.PossitionControl app.PossitionControl...
                                                       app.PossitionControl app.PossitionControl app.PossitionControl],8)))';
                                                   
            % Start/stop CAN Data
            messageStartStopCan=canMessage(85,false,6);
            messageStartStopCan.Data=(hex2dec(ndec2hex(app,[0 0 0 0 0 0],8)))';            %NOT TO TOUCH
            
            %%---------------- Load Data  ---------------------------------

%             transmit(app.canch,messageMinAngles)
%             pause(1)
%             transmit(app.canch,messageMaxAngles)
%             pause(1)
            transmit(app.canch,messagePercentageAssistance)
            pause(1)
            transmit(app.canch,messageTypeControl)
            pause(1)
            transmit(app.canch,messagePosSetPoint)
            pause(1)

            app.InicializeStatusLamp.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CANStatusLampLabel = uilabel(app.UIFigure);
            app.CANStatusLampLabel.Text = 'Param Status';
            app.CANStatusLamp = uilamp(app.UIFigure);
            app.CANconfigurationButton = uibutton(app.UIFigure, 'push');
            app.CANconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @CANconfigurationButtonPushed, true);
            app.CANconfigurationButton.Text = 'CAN configuration';
            app.StartStopSamplingButton = uibutton(app.UIFigure, 'push');
            app.StartStopSamplingButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopSamplingButtonPushed, true);
            app.StartStopSamplingButton.Text = 'Start/Stop Sampling';
            app.CANStatusLamp_2Label = uilabel(app.UIFigure);
            app.CANStatusLamp_2Label.Text = 'CAN Status';
            app.CANStatusLamp_2 = uilamp(app.UIFigure);
            app.SamplingH3Label = uilabel(app.UIFigure);
            app.SamplingH3Label.Text = 'Sampling H3';
            app.TestingsoftwareLabel = uilabel(app.UIFigure);
            app.TestingsoftwareLabel.Text = {'Testing '; 'software'};
            app.MoveankleButton = uibutton(app.UIFigure, 'push');
            app.MoveankleButton.ButtonPushedFcn = createCallbackFcn(app, @MoveankleButtonPushed, true);
            app.MoveankleButton.Text = 'Move ankle';
            app.InicializeButton = uibutton(app.UIFigure, 'push');
            app.InicializeButton.ButtonPushedFcn = createCallbackFcn(app, @InicializeButtonPushed, true);
            app.InicializeButton.Text = 'Inicialize';
            app.InicilizestatusLabel = uilabel(app.UIFigure);
            app.InicilizestatusLabel.Text = 'Inicilize status';
            app.InicializeStatusLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'angle (º)')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'time (s)')
            ylabel(app.UIAxes_2, 'angle (º)')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'time (s)')
            ylabel(app.UIAxes_3, 'angle (º)')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'time (s)')
            ylabel(app.UIAxes_4, 'angle (º)')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'time (s)')
            ylabel(app.UIAxes_5, 'angle (º)')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_6, 'time (s)')
            ylabel(app.UIAxes_6, 'angle (º)')
            app.UIplotrhip = uiaxes(app.UIFigure);
            xlabel(app.UIplotrhip, 'time (s)')
            ylabel(app.UIplotrhip, 'angle (º)')
            app.UIplotrknee = uiaxes(app.UIFigure);
            xlabel(app.UIplotrknee, 'time (s)')
            ylabel(app.UIplotrknee, 'angle (º)')
            app.UIplotrankle = uiaxes(app.UIFigure);
            xlabel(app.UIplotrankle, 'time (s)')
            ylabel(app.UIplotrankle, 'angle (º)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Mario

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end